classdef appAddDevice_OPMrubin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OPMRubinUIFigure               matlab.ui.Figure
        EditField                      matlab.ui.control.EditField
        DisplaywavelengthButton        matlab.ui.control.Button
        OPMcomPortsEditField           matlab.ui.control.EditField
        COMportsEditFieldLabel         matlab.ui.control.Label
        okButton                       matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        OPMnameEditField               matlab.ui.control.EditField
        OPMnameEditFieldLabel          matlab.ui.control.Label
        SerialportDropDown             matlab.ui.control.DropDown
        SerialportDropDownLabel        matlab.ui.control.Label
        RefreshButton                  matlab.ui.control.Button
        Switch                         matlab.ui.control.Switch
        Panel                          matlab.ui.container.Panel
        SaveButton                     matlab.ui.control.Button
        WLabel                         matlab.ui.control.Label
        dBmLabel                       matlab.ui.control.Label
        PowerWEditField                matlab.ui.control.NumericEditField
        PowerEditField                 matlab.ui.control.NumericEditField
        ReadpowerButton                matlab.ui.control.Button
        ChoosewavelengthDropDown       matlab.ui.control.DropDown
        ChoosewavelengthDropDownLabel  matlab.ui.control.Label
        OPMRubinLabel                  matlab.ui.control.Label
    end

    properties (Access = private)
        callingApp
        folder
        userText
    end
    properties (Access = public)
        opmArr
    end
    methods (Access = public)
        function refreshSerialportDropDown(app)
            app.SerialportDropDown.Items = serialportlist("available");
        end
        function output = connect(app)
            try
                app.opmArr = [];
                app.opmArr = OpticalPowerMeterArr(app);
                output = getInfoOpenComArr(app.opmArr);
                app.Panel.Enable = 'on';
            catch ME
                
                app.Switch.Value = "Disconnect";

                report = getReport(ME);
                uialert(app.callingApp.GreatUniterUIFigure,report,'Error Message','Interpreter','html');
                output = "Wrong connection";
                %app.ConnectSwitch.Value = "Disconnect";


            end
        end
        function output = disconnect(app)
            if ~isequal(class(app.opmArr),'double')
                app.opmArr = deleteVirtualObjectArr(app.opmArr);
                output = getInfoCloseComArr(app.opmArr);
                app.Panel.Enable = 'off';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.callingApp = MainApp;
            refreshSerialportDropDown(app);
            app.folder = MainApp.folder;
            app.userText = MainApp.userText;
            
            %название файла для сохранения данных ?
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "Connect"
                output = connect(app);
            elseif value == "Disconnect"
                output = disconnect(app);
            end
            addStrOutput(app.callingApp,output);
        end

        % Callback function
        function RunButtonPushed(app, event)
            value = app.Tree.SelectedNodes;
            if value == app.ReadpowerRubinNode
                
            elseif value == app.DispwavelengthRubinNode
                
            elseif value == app.SavepowertableNode
                
            end
            
        end

        % Button pushed function: ReadpowerButton
        function ReadpowerButtonPushed(app, event)
            app.opmArr = readPowerdBmArr(app.opmArr);
                output = getInfoPowerArr(app.opmArr);
                addStrOutput(app.callingApp,output);
        end

        % Button pushed function: DisplaywavelengthButton
        function DisplaywavelengthButtonPushed(app, event)
            output = getInfoWavelengthArr(app.opmArr);
            addStrOutput(app.callingApp,output);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            output = "function is not ready";
            addStrOutput(app.callingApp,output);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OPMRubinUIFigure = uifigure('Visible', 'off');
            app.OPMRubinUIFigure.Name = 'OPM Rubin';
            app.OPMRubinUIFigure.Icon = 'gu_device.png';
            app.OPMRubinLabel = uilabel(app.OPMRubinUIFigure);
            app.OPMRubinLabel.Text = 'OPM (Rubin)';
            app.Panel = uipanel(app.OPMRubinUIFigure);
            app.Panel.Enable = 'off';
            app.ChoosewavelengthDropDownLabel = uilabel(app.Panel);
            app.ChoosewavelengthDropDownLabel.Text = 'Choose wavelength';
            app.ChoosewavelengthDropDown = uidropdown(app.Panel);
            app.ChoosewavelengthDropDown.Items = {'850 nm', '980 nm', '1310 nm', '1490 nm', '1550 nm', '1650 nm'};
            app.ChoosewavelengthDropDown.Value = '1550 nm';
            app.ReadpowerButton = uibutton(app.Panel, 'push');
            app.ReadpowerButton.ButtonPushedFcn = createCallbackFcn(app, @ReadpowerButtonPushed, true);
            app.ReadpowerButton.Text = 'Read power';
            app.PowerEditField = uieditfield(app.Panel, 'numeric');
            app.PowerWEditField = uieditfield(app.Panel, 'numeric');
            app.dBmLabel = uilabel(app.Panel);
            app.dBmLabel.Text = 'dBm';
            app.WLabel = uilabel(app.Panel);
            app.WLabel.Text = 'W';
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Panel_2 = uipanel(app.OPMRubinUIFigure);
            app.Switch = uiswitch(app.Panel_2, 'slider');
            app.Switch.Items = {'Disconnect', 'Connect'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Disconnect';
            app.RefreshButton = uibutton(app.Panel_2, 'push');
            app.RefreshButton.Icon = 'gu_refresh.png';
            app.RefreshButton.Text = '';
            app.SerialportDropDownLabel = uilabel(app.Panel_2);
            app.SerialportDropDownLabel.Text = 'Serial port';
            app.SerialportDropDown = uidropdown(app.Panel_2);
            app.SerialportDropDown.Items = {'COMXX'};
            app.SerialportDropDown.Value = 'COMXX';
            app.OPMnameEditFieldLabel = uilabel(app.Panel_2);
            app.OPMnameEditFieldLabel.Text = 'OPM name';
            app.OPMnameEditField = uieditfield(app.Panel_2, 'text');
            app.okButton = uibutton(app.OPMRubinUIFigure, 'push');
            app.okButton.Enable = 'off';
            app.okButton.Text = 'ok';
            app.COMportsEditFieldLabel = uilabel(app.OPMRubinUIFigure);
            app.COMportsEditFieldLabel.Text = 'COM-ports';
            app.OPMcomPortsEditField = uieditfield(app.OPMRubinUIFigure, 'text');
            app.OPMcomPortsEditField.Value = 'COM19 COM12';
            app.DisplaywavelengthButton = uibutton(app.OPMRubinUIFigure, 'push');
            app.DisplaywavelengthButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaywavelengthButtonPushed, true);
            app.DisplaywavelengthButton.Text = 'Display wavelength';
            app.EditField = uieditfield(app.OPMRubinUIFigure, 'text');
            % Show the figure after all components are created
            app.OPMRubinUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appAddDevice_OPMrubin(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OPMRubinUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OPMRubinUIFigure)
        end
    end
end